classdef Digits_Recognition_Start < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        PredictVoiceRecordingLabel      matlab.ui.control.Label
        StopRecordingButton             matlab.ui.control.Button
        RecordButton                    matlab.ui.control.Button
        ListenRecordingButton           matlab.ui.control.Button
        PredictButton                   matlab.ui.control.Button
        PredictedDigitsTextAreaLabel    matlab.ui.control.Label
        PredictedDigitsTextArea         matlab.ui.control.TextArea
        BacktomenuButton_2              matlab.ui.control.Button
        PredictUsingLiveRecordingButton  matlab.ui.control.Button
        PredictUsingPrerecordedFileButton  matlab.ui.control.Button
        Panel2                          matlab.ui.container.Panel
        PredictPreRecordedLabel         matlab.ui.control.Label
        SelectafileButton               matlab.ui.control.Button
        PredictButton_2                 matlab.ui.control.Button
        PredictedDigitsTextArea_2Label  matlab.ui.control.Label
        PredictedDigitsTextArea_2       matlab.ui.control.TextArea
        SelectedFileLabel               matlab.ui.control.Label
        BacktomenuButton                matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StopRecordingButton
        function StopRecordingButtonPushed(app, event)
            global rec;
            global fs;
            app.RecordButton.Enable=true;
            app.StopRecordingButton.Enable=false;
            app.ListenRecordingButton.Enable=true;
            stop(rec);
            recording = getaudiodata(rec);
            audiowrite('recording.wav',recording,fs);
        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            global rec
            global fs;
            app.RecordButton.Enable=false;
            app.StopRecordingButton.Enable=true;
            app.ListenRecordingButton.Enable=false;
            fs = 8000 ; 
            nBits = 16 ; 
            nChannels = 1 ; 
            ID = -1;
            rec = audiorecorder(fs,nBits,nChannels,ID); 
            record(rec);
        end

        % Button pushed function: ListenRecordingButton
        function ListenRecordingButtonPushed(app, event)
            [audioIn,fs] = audioread('recording.wav');
            sound(audioIn,fs,16);
        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            file="recording.wav";
            digits=predictions(file).';
            app.PredictedDigitsTextArea.Value =cellstr(num2str(digits));
        end

        % Button pushed function: SelectafileButton
        function SelectafileButtonPushed(app, event)
           global file;
           global path;
            [file,path] = uigetfile('*.wav; *.mp3');
                if file
                    app.SelectedFileLabel.Text=sprintf("Selected file: %s%s ", path,file);
                    app.PredictButton_2.Enable=true;
                else
                    app.SelectedFileLabel.Text=sprintf("Selected file: ");
                    app.PredictButton_2.Enable=false;
                end
        end

        % Button pushed function: PredictUsingLiveRecordingButton
        function PredictUsingLiveRecordingButtonPushed(app, event)
            app.PredictUsingLiveRecordingButton.Visible=false;
            app.PredictUsingPrerecordedFileButton.Visible=false;
            app.PredictedDigitsTextArea.Value="";
            app.Panel.Visible = true;
        end

        % Button pushed function: PredictUsingPrerecordedFileButton
        function PredictUsingPrerecordedFileButtonPushed(app, event)
           app.PredictUsingLiveRecordingButton.Visible=false;
            app.PredictUsingPrerecordedFileButton.Visible=false;
            app.PredictedDigitsTextArea_2.Value="";
            app.SelectedFileLabel.Text="Selected file: ";
            app.Panel2.Visible = true;
        end

        % Button pushed function: BacktomenuButton, 
        % BacktomenuButton_2
        function BacktomenuButtonPushed(app, event)
            app.Panel.Visible=false;
            app.Panel2.Visible=false;
            app.PredictUsingLiveRecordingButton.Visible=true;
            app.PredictUsingPrerecordedFileButton.Visible=true;
            app.PredictButton_2.Enable=false;
        end

        % Button pushed function: PredictButton_2
        function PredictButton_2Pushed(app, event)
            global file;
            global path;
            digits=predictions(fullfile(path,file)).';
            app.PredictedDigitsTextArea_2.Value =cellstr(num2str(digits));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.Panel.Visible = 'off';
            app.PredictVoiceRecordingLabel = uilabel(app.Panel);
            app.PredictVoiceRecordingLabel.Text = 'Predict Voice Recording';
            app.StopRecordingButton = uibutton(app.Panel, 'push');
            app.StopRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StopRecordingButtonPushed, true);
            app.StopRecordingButton.Enable = 'off';
            app.StopRecordingButton.Text = 'Stop Recording';
            app.RecordButton = uibutton(app.Panel, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.ListenRecordingButton = uibutton(app.Panel, 'push');
            app.ListenRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @ListenRecordingButtonPushed, true);
            app.ListenRecordingButton.Enable = 'off';
            app.ListenRecordingButton.Text = 'Listen Recording';
            app.PredictButton = uibutton(app.Panel, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict!';
            app.PredictedDigitsTextAreaLabel = uilabel(app.Panel);
            app.PredictedDigitsTextAreaLabel.Text = 'Predicted Digits';
            app.PredictedDigitsTextArea = uitextarea(app.Panel);
            app.PredictedDigitsTextArea.Editable = 'off';
            app.BacktomenuButton_2 = uibutton(app.Panel, 'push');
            app.BacktomenuButton_2.ButtonPushedFcn = createCallbackFcn(app, @BacktomenuButtonPushed, true);
            app.BacktomenuButton_2.Text = {'Back to menu'; ''};
            app.PredictUsingLiveRecordingButton = uibutton(app.UIFigure, 'push');
            app.PredictUsingLiveRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @PredictUsingLiveRecordingButtonPushed, true);
            app.PredictUsingLiveRecordingButton.Text = {'Predict Using '; 'Live Recording'};
            app.PredictUsingPrerecordedFileButton = uibutton(app.UIFigure, 'push');
            app.PredictUsingPrerecordedFileButton.ButtonPushedFcn = createCallbackFcn(app, @PredictUsingPrerecordedFileButtonPushed, true);
            app.PredictUsingPrerecordedFileButton.Text = {'Predict Using '; 'Pre-recorded File'; ''};
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'Panel2';
            app.Panel2.Visible = 'off';
            app.PredictPreRecordedLabel = uilabel(app.Panel2);
            app.PredictPreRecordedLabel.Text = 'Predict Pre-Recorded';
            app.SelectafileButton = uibutton(app.Panel2, 'push');
            app.SelectafileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectafileButtonPushed, true);
            app.SelectafileButton.Text = {'Select a file ...'; ''};
            app.PredictButton_2 = uibutton(app.Panel2, 'push');
            app.PredictButton_2.ButtonPushedFcn = createCallbackFcn(app, @PredictButton_2Pushed, true);
            app.PredictButton_2.Enable = 'off';
            app.PredictButton_2.Text = 'Predict!';
            app.PredictedDigitsTextArea_2Label = uilabel(app.Panel2);
            app.PredictedDigitsTextArea_2Label.Text = 'Predicted Digits';
            app.PredictedDigitsTextArea_2 = uitextarea(app.Panel2);
            app.PredictedDigitsTextArea_2.Editable = 'off';
            app.SelectedFileLabel = uilabel(app.Panel2);
            app.SelectedFileLabel.Text = 'Selected File: ';
            app.BacktomenuButton = uibutton(app.Panel2, 'push');
            app.BacktomenuButton.ButtonPushedFcn = createCallbackFcn(app, @BacktomenuButtonPushed, true);
            app.BacktomenuButton.Text = {'Back to menu'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Digits_Recognition_Start

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end