classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        PredictRecordingLabel         matlab.ui.control.Label
        StopRecordingButton           matlab.ui.control.Button
        RecordButton                  matlab.ui.control.Button
        ListenRecordingButton         matlab.ui.control.Button
        PredictedDigitsTextAreaLabel  matlab.ui.control.Label
        PredictedDigitsTextArea       matlab.ui.control.TextArea
        PredictButton                 matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StopRecordingButton
        function StopRecordingButtonPushed(app, event)
            global rec;
            global fs;
            app.RecordButton.Enable=true;
            app.StopRecordingButton.Enable=false;
            app.ListenRecordingButton.Enable=true;
            stop(rec);
            recording = getaudiodata(rec);
            audiowrite('recording.wav',recording,fs);
        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            global rec
            global fs;
            app.RecordButton.Enable=false;
            app.StopRecordingButton.Enable=true;
            app.ListenRecordingButton.Enable=false;
            fs = 8000 ; 
            nBits = 16 ; 
            nChannels = 1 ; 
            ID = -1;
            rec = audiorecorder(fs,nBits,nChannels,ID); 
            record(rec);
        end

        % Button pushed function: ListenRecordingButton
        function ListenRecordingButtonPushed(app, event)
            [audioIn,fs] = audioread('recording.wav');
            sound(audioIn,fs,16);
        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            file="recording.wav"
            digits=predictions(file).';
            app.PredictedDigitsTextArea.Value =cellstr(num2str(digits));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.PredictRecordingLabel = uilabel(app.UIFigure);
            app.PredictRecordingLabel.Text = 'Predict Recording';
            app.StopRecordingButton = uibutton(app.UIFigure, 'push');
            app.StopRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StopRecordingButtonPushed, true);
            app.StopRecordingButton.Enable = 'off';
            app.StopRecordingButton.Text = 'Stop Recording';
            app.RecordButton = uibutton(app.UIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.ListenRecordingButton = uibutton(app.UIFigure, 'push');
            app.ListenRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @ListenRecordingButtonPushed, true);
            app.ListenRecordingButton.Enable = 'off';
            app.ListenRecordingButton.Text = 'Listen Recording';
            app.PredictedDigitsTextAreaLabel = uilabel(app.UIFigure);
            app.PredictedDigitsTextAreaLabel.Text = 'Predicted Digits';
            app.PredictedDigitsTextArea = uitextarea(app.UIFigure);
            app.PredictedDigitsTextArea.Editable = 'off';
            app.PredictButton = uibutton(app.UIFigure, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end